classdef Copy_of_Data_Collection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ankleIC                        matlab.ui.control.NumericEditField
        kneeIC                         matlab.ui.control.NumericEditField
        hipIC                          matlab.ui.control.NumericEditField
        MuscleStimulatedDropDown       matlab.ui.control.DropDown
        MuscleStimulatedDropDownLabel  matlab.ui.control.Label
        TrialInitialConditionsLabel    matlab.ui.control.Label
        CloseSerialPortLabel           matlab.ui.control.Label
        EndButton                      matlab.ui.control.Button
        COMSelectedEditField           matlab.ui.control.EditField
        COMSelectedEditFieldLabel      matlab.ui.control.Label
        BeginSerialCommunicationLabel  matlab.ui.control.Label
        EnterSerialPortCOMEditField    matlab.ui.control.EditField
        EnterSerialPortCOMEditFieldLabel  matlab.ui.control.Label
        TareAnkleButton                matlab.ui.control.Button
        TareKneeButton                 matlab.ui.control.Button
        TareHipButton                  matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        AnkleAngleEditField            matlab.ui.control.NumericEditField
        AnkleAngleEditFieldLabel       matlab.ui.control.Label
        KneeAngleEditField             matlab.ui.control.NumericEditField
        KneeAngleEditFieldLabel        matlab.ui.control.Label
        HipAngleEditField              matlab.ui.control.NumericEditField
        HipAngleEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        COMport          % COM port of Arduino
        continuous       % variable to turn on or off continuous data streaming from serial port
        hipTareValue     % variable to store tared hip angle
        kneeTareValue    % variable to store tared knee angle
        ankleTareValue   % variable to store tared ankle angle
        anglesRaw        % variable to store raw encoder angle readings
        anglesCurrent    % variable to store actual encoder angle readings
        hipIC_Current    % variable to store current desired hip initial condition
        kneeIC_Current   % variable to store current desired knee initial condition
        ankleIC_Current  % variable to store current desired ankle initial condition
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % begin serial commuinication with specified port and baud rate
            s = serialport(app.COMport, 115200);
            
            % set up variable for continuous reading of serial data
            app.continuous = 1;

            % initialize tared angle values
            app.hipTareValue = 0;
            app.kneeTareValue = 0;
            app.ankleTareValue = 0;
            
            % initialize current initial condition angles
            app.hipIC_Current = 1;
            app.kneeIC_Current = 1;
            app.ankleIC_Current = 1;
            
            % data streamed from Arduino will be in format:
            % [hipangle kneeangle ankleangle time(ms)]
            while app.continuous == 1
                anglesText = readline(s);                      % read serial data (ASCII)
                
                if(~isempty(str2num(anglesText)))              % make sure the data we are reading is a number
                    app.anglesRaw = str2num(anglesText);       % if it is a number save it as the raw angle
                    
                    % convert raw angle to current angle with known tared values
                    app.anglesCurrent(1) = app.anglesRaw(1) + app.hipTareValue;   
                    app.anglesCurrent(2) = app.anglesRaw(2) + app.kneeTareValue;
                    app.anglesCurrent(3) = app.anglesRaw(3) + app.ankleTareValue;
                    
                    app.HipAngleEditField.Value = app.anglesCurrent(1);        % display current hip angle
                    app.KneeAngleEditField.Value = app.anglesCurrent(2);       % display current knee angle
                    app.AnkleAngleEditField.Value = app.anglesCurrent(3);      % display current ankle angle                           
                end
                
                if abs(app.anglesCurrent(1) - app.hipIC_Current)/app.hipIC_Current < 0.02
                    app.HipAngleEditField.BackgroundColor = [0.39 0.83 0.07];
                else
                    app.HipAngleEditField.BackgroundColor = [1 1 1];
                end
            end
           
        end

        % Button pushed function: TareHipButton
        function TareHipButtonPushed(app, event)
            app.hipTareValue = 90 - app.anglesCurrent(1);
            
        end

        % Button pushed function: TareKneeButton
        function TareKneeButtonPushed(app, event)
            app.kneeTareValue = 94 - app.anglesCurrent(2);
            
        end

        % Button pushed function: TareAnkleButton
        function TareAnkleButtonPushed(app, event)
            app.ankleTareValue = 180 - app.anglesCurrent(3) ;
            
        end

        % Value changed function: EnterSerialPortCOMEditField
        function EnterSerialPortCOMEditFieldValueChanged(app, event)
            app.COMport = app.EnterSerialPortCOMEditField.Value;
            app.COMSelectedEditField.Value = app.COMport;
            
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.continuous = 2;
            clear s
        end

        % Value changed function: MuscleStimulatedDropDown
        function MuscleStimulatedDropDownValueChanged(app, event)
            muscleDesired = app.MuscleStimulatedDropDown.Value;
            muscleNames = {'IP' 'GS' 'ST' 'ST2' 'VL' 'BFp' 'BFa'};
            indexMuscle = find(ismember(muscleNames,muscleDesired));
            
            muscleIC = [93 143 155;      % IP
                        93 112 141;      % GS
                        113 88 168;      % ST
                        107 88 140;      % ST2
                        102 128 141;     % VL
                        116 87 99;       % BFp
                        108 105 142];    % BFa
            
            app.hipIC_Current = muscleIC(indexMuscle,1);
            app.kneeIC_Current = muscleIC(indexMuscle,2);
            app.ankleIC_Current = muscleIC(indexMuscle,3);
            
            app.hipIC.Value = app.hipIC_Current;
            app.kneeIC.Value = app.kneeIC_Current;
            app.ankleIC.Value = app.ankleIC_Current;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HipAngleEditFieldLabel = uilabel(app.UIFigure);
            app.HipAngleEditFieldLabel.Text = 'Hip Angle';
            app.HipAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.KneeAngleEditFieldLabel = uilabel(app.UIFigure);
            app.KneeAngleEditFieldLabel.Text = 'Knee Angle';
            app.KneeAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnkleAngleEditFieldLabel = uilabel(app.UIFigure);
            app.AnkleAngleEditFieldLabel.Text = 'Ankle Angle';
            app.AnkleAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TareHipButton = uibutton(app.UIFigure, 'push');
            app.TareHipButton.ButtonPushedFcn = createCallbackFcn(app, @TareHipButtonPushed, true);
            app.TareHipButton.Text = 'Tare Hip';
            app.TareKneeButton = uibutton(app.UIFigure, 'push');
            app.TareKneeButton.ButtonPushedFcn = createCallbackFcn(app, @TareKneeButtonPushed, true);
            app.TareKneeButton.Text = 'Tare Knee';
            app.TareAnkleButton = uibutton(app.UIFigure, 'push');
            app.TareAnkleButton.ButtonPushedFcn = createCallbackFcn(app, @TareAnkleButtonPushed, true);
            app.TareAnkleButton.Text = 'Tare Ankle';
            app.EnterSerialPortCOMEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSerialPortCOMEditFieldLabel.Text = 'Enter Serial Port (COM)';
            app.EnterSerialPortCOMEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSerialPortCOMEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSerialPortCOMEditFieldValueChanged, true);
            app.BeginSerialCommunicationLabel = uilabel(app.UIFigure);
            app.BeginSerialCommunicationLabel.Text = 'Begin Serial Communication';
            app.COMSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.COMSelectedEditFieldLabel.Text = 'COM Selected';
            app.COMSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.CloseSerialPortLabel = uilabel(app.UIFigure);
            app.CloseSerialPortLabel.Text = 'Close Serial Port';
            app.TrialInitialConditionsLabel = uilabel(app.UIFigure);
            app.TrialInitialConditionsLabel.Text = 'Trial Initial Conditions';
            app.MuscleStimulatedDropDownLabel = uilabel(app.UIFigure);
            app.MuscleStimulatedDropDownLabel.Text = 'Muscle Stimulated';
            app.MuscleStimulatedDropDown = uidropdown(app.UIFigure);
            app.MuscleStimulatedDropDown.Items = {'IP', 'GS', 'ST', 'ST2', 'VL', 'BFp', 'BFa'};
            app.MuscleStimulatedDropDown.ValueChangedFcn = createCallbackFcn(app, @MuscleStimulatedDropDownValueChanged, true);
            app.MuscleStimulatedDropDown.Value = 'IP';
            app.hipIC = uieditfield(app.UIFigure, 'numeric');
            app.kneeIC = uieditfield(app.UIFigure, 'numeric');
            app.ankleIC = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_Data_Collection_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end