classdef Data_Collection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CloseSerialPortLabel           matlab.ui.control.Label
        EndButton                      matlab.ui.control.Button
        COMSelectedEditField           matlab.ui.control.EditField
        COMSelectedEditFieldLabel      matlab.ui.control.Label
        BeginSerialCommunicationLabel  matlab.ui.control.Label
        EnterSerialPortCOMEditField    matlab.ui.control.EditField
        EnterSerialPortCOMEditFieldLabel  matlab.ui.control.Label
        TareAnkleButton                matlab.ui.control.Button
        TareKneeButton                 matlab.ui.control.Button
        TareHipButton                  matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        AnkleAngleEditField            matlab.ui.control.NumericEditField
        AnkleAngleEditFieldLabel       matlab.ui.control.Label
        KneeAngleEditField             matlab.ui.control.NumericEditField
        KneeAngleEditFieldLabel        matlab.ui.control.Label
        HipAngleEditField              matlab.ui.control.NumericEditField
        HipAngleEditFieldLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clear
            clc
            
            % begin serial commuinication with specified port and baud rate
            s = serialport('COM11', 115200);
            
            % set up variable for continuous reading of serial data
            continuous = 1;
            
            pause(1);
            
            % data streamed from Arduino will be in format:
            % [hipangle kneeangle ankleangle time(ms)]
            while continuous == 1
                angles_text = readline(s);                          % read serial data (ASCII)
                        if(~isempty(str2num(angles_text)))          % Make sure the data we are reading is actually a number
                            angles_current = str2num(angles_text);  % If it is a number, store it
                            app.HipAngleEditField.Value = angles_current(1);     % display current hip angle
                            app.KneeAngleEditField.Value = angles_current(2);    % display current knee angle
                            app.AnkleAngleEditField.Value = angles_current(3);   % display current ankle angle                           
                        end
            end
            
            
        end

        % Button pushed function: TareHipButton
        function TareHipButtonPushed(app, event)
            HipTareValue = app.HipAngleEditField.Value;
            app.HipAngleEditField.Value = app.HipAngleEditField.Value - HipTareValue;
            
        end

        % Button pushed function: TareKneeButton
        function TareKneeButtonPushed(app, event)
            KneeTareValue = app.KneeAngleEditField.Value;
            app.KneeAngleEditField.Value = app.KneeAngleEditField.Value - KneeTareValue;
            
        end

        % Button pushed function: TareAnkleButton
        function TareAnkleButtonPushed(app, event)
            AnkleTareValue = app.AnkleAngleEditField.Value;
            app.AnkleAngleEditField.Value = app.AnkleAngleEditField.Value - AnkleTareValue;            
        end

        % Value changed function: EnterSerialPortCOMEditField
        function EnterSerialPortCOMEditFieldValueChanged(app, event)
            COMport = app.EnterSerialPortCOMEditField.Value;
            app.COMSelectedEditField.Value = COMport;
            
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            continuous = 2;
            clear s
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HipAngleEditFieldLabel = uilabel(app.UIFigure);
            app.HipAngleEditFieldLabel.Text = 'Hip Angle';
            app.HipAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.KneeAngleEditFieldLabel = uilabel(app.UIFigure);
            app.KneeAngleEditFieldLabel.Text = 'Knee Angle';
            app.KneeAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnkleAngleEditFieldLabel = uilabel(app.UIFigure);
            app.AnkleAngleEditFieldLabel.Text = 'Ankle Angle';
            app.AnkleAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TareHipButton = uibutton(app.UIFigure, 'push');
            app.TareHipButton.ButtonPushedFcn = createCallbackFcn(app, @TareHipButtonPushed, true);
            app.TareHipButton.Text = 'Tare Hip';
            app.TareKneeButton = uibutton(app.UIFigure, 'push');
            app.TareKneeButton.ButtonPushedFcn = createCallbackFcn(app, @TareKneeButtonPushed, true);
            app.TareKneeButton.Text = 'Tare Knee';
            app.TareAnkleButton = uibutton(app.UIFigure, 'push');
            app.TareAnkleButton.ButtonPushedFcn = createCallbackFcn(app, @TareAnkleButtonPushed, true);
            app.TareAnkleButton.Text = {'Tare Ankle'; ''};
            app.EnterSerialPortCOMEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSerialPortCOMEditFieldLabel.Text = 'Enter Serial Port (COM)';
            app.EnterSerialPortCOMEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSerialPortCOMEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSerialPortCOMEditFieldValueChanged, true);
            app.BeginSerialCommunicationLabel = uilabel(app.UIFigure);
            app.BeginSerialCommunicationLabel.Text = 'Begin Serial Communication';
            app.COMSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.COMSelectedEditFieldLabel.Text = 'COM Selected';
            app.COMSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.CloseSerialPortLabel = uilabel(app.UIFigure);
            app.CloseSerialPortLabel.Text = 'Close Serial Port';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Collection_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end