classdef Copy_of_Data_Collection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HipAngleEditFieldLabel         matlab.ui.control.Label
        HipAngleEditField              matlab.ui.control.NumericEditField
        KneeAngleEditFieldLabel        matlab.ui.control.Label
        KneeAngleEditField             matlab.ui.control.NumericEditField
        AnkleAngleEditFieldLabel       matlab.ui.control.Label
        AnkleAngleEditField            matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.Button
        TareHipButton                  matlab.ui.control.Button
        TareKneeButton                 matlab.ui.control.Button
        TareAnkleButton                matlab.ui.control.Button
        EnterSerialPortCOMEditFieldLabel  matlab.ui.control.Label
        EnterSerialPortCOMEditField    matlab.ui.control.EditField
        BeginSerialCommunicationLabel  matlab.ui.control.Label
        COMSelectedEditFieldLabel      matlab.ui.control.Label
        COMSelectedEditField           matlab.ui.control.EditField
        EndButton                      matlab.ui.control.Button
        CloseSerialPortLabel           matlab.ui.control.Label
        TrialInitialConditionsLabel    matlab.ui.control.Label
        MuscleStimulatedDropDownLabel  matlab.ui.control.Label
        MuscleStimulatedDropDown       matlab.ui.control.DropDown
        hipIC                          matlab.ui.control.NumericEditField
        kneeIC                         matlab.ui.control.NumericEditField
        ankleIC                        matlab.ui.control.NumericEditField
        DropTrialNoEditFieldLabel      matlab.ui.control.Label
        DropTrialNoEditField           matlab.ui.control.NumericEditField
        DropandSaveButton              matlab.ui.control.Button
        DisplayFileName                matlab.ui.control.EditField
        LengthtoCollectEditFieldLabel  matlab.ui.control.Label
        LengthToCollectEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        COMport          % COM port of Arduino
        continuous       % variable to turn on or off continuous data streaming from serial port
        hipTareValue     % tared hip angle
        kneeTareValue    % tared knee angle
        ankleTareValue   % tared ankle angle
        anglesRaw        % raw encoder angle readings
        anglesCurrent    % actual encoder angle readings
        hipIC_Current    % current desired hip initial condition based on selected muscle
        kneeIC_Current   % current desired knee initial condition based on selected muscle
        ankleIC_Current  % current desired ankle initial condition based on selected muscle
        muscleNames      % names of all the muscles being tested
        indexMuscle      % index of muscle currently being tested
        muscleDesired    % name of muscle currently being tested
        dropCounter      % drop trial number for each muscle
        lengthToCollect  % number of data points to save for each drop trial
        dataSave         % matrix to store data for each drop trial

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % begin serial communication with specified port and baud rate
            s = serialport(app.COMport, 115200);
            
            % set up variable for continuous reading of serial data
            app.continuous = 1;

            % initialize tared angle values
            app.hipTareValue = 0;
            app.kneeTareValue = 0;
            app.ankleTareValue = 0;
            
            % initialize current initial condition angles
            app.hipIC_Current = 1;
            app.kneeIC_Current = 1;
            app.ankleIC_Current = 1;
            
            % initialize drop trial counter
            app.dropCounter = [1 1 1 1 1 1 1];
            
            % default 500 data points to collect per trial
            app.lengthToCollect = 500;
            app.LengthToCollectEditField.Value = app.lengthToCollect;
            
            % initialize data save matrix
            app.dataSave = zeros(app.lengthToCollect,4);
            
            % add path to folder for saving results
            addpath('C:\GitHub\Quadruped_Robot\Code\Matlab\Analysis\DampedLeg_Krnacik\Haonan\Comparison\Trial 2');
            
            % data streamed from Arduino will be in format:
            % [hipangle kneeangle ankleangle time(ms)]
            while app.continuous == 1
                anglesText = readline(s);                      % read serial data (ASCII)
                
                if(~isempty(str2num(anglesText)))              % make sure the data we are reading is a number
                    app.anglesRaw = str2num(anglesText);       % if it is a number save it as the raw angle
                    
                    % convert raw angle to current angle with known tared values
                    app.anglesCurrent(1) = app.anglesRaw(1) + app.hipTareValue;   
                    app.anglesCurrent(2) = app.anglesRaw(2) + app.kneeTareValue;
                    app.anglesCurrent(3) = app.anglesRaw(3) + app.ankleTareValue;
                    
                    % display actual current angles
                    app.HipAngleEditField.Value = app.anglesCurrent(1);
                    app.KneeAngleEditField.Value = app.anglesCurrent(2);
                    app.AnkleAngleEditField.Value = app.anglesCurrent(3);                         
                end
                
                % if displayed angles are within 1° of desired trial initial condition - highlight green
                if abs(app.anglesCurrent(1) - app.hipIC_Current) < 1
                    app.HipAngleEditField.BackgroundColor = [0.39 0.83 0.07];
                else
                    app.HipAngleEditField.BackgroundColor = [1 1 1];
                end
                
                if abs(app.anglesCurrent(2) - app.kneeIC_Current) < 1
                    app.KneeAngleEditField.BackgroundColor = [0.39 0.83 0.07];
                else
                    app.KneeAngleEditField.BackgroundColor = [1 1 1];
                end
                                
                if abs(app.anglesCurrent(3) - app.ankleIC_Current) < 1
                    app.AnkleAngleEditField.BackgroundColor = [0.39 0.83 0.07];
                else
                    app.AnkleAngleEditField.BackgroundColor = [1 1 1];
                end   
            end
           
        end

        % Button pushed function: TareHipButton
        function TareHipButtonPushed(app, event)
            app.hipTareValue = 90 - app.anglesCurrent(1);
            
        end

        % Button pushed function: TareKneeButton
        function TareKneeButtonPushed(app, event)
            app.kneeTareValue = 94 - app.anglesCurrent(2);
            
        end

        % Button pushed function: TareAnkleButton
        function TareAnkleButtonPushed(app, event)
            app.ankleTareValue = 180 - app.anglesCurrent(3) ;
            
        end

        % Value changed function: EnterSerialPortCOMEditField
        function EnterSerialPortCOMEditFieldValueChanged(app, event)
            app.COMport = app.EnterSerialPortCOMEditField.Value;
            app.COMSelectedEditField.Value = app.COMport;
            
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.continuous = 2;     % stop reading serial data
            clear s;                % close serial port
        end

        % Value changed function: MuscleStimulatedDropDown
        function MuscleStimulatedDropDownValueChanged(app, event)
            app.muscleDesired = app.MuscleStimulatedDropDown.Value;     % select muscle trial to evaluate from dropdown menu
            app.muscleNames = {'IP' 'GS' 'ST' 'ST2' 'VL' 'BFp' 'BFa'};  % muscle name array of stimulated in rat trials
            app.indexMuscle = find(ismember(app.muscleNames,app.muscleDesired));  % find the index number of muscle chosen in name array
            app.DropTrialNoEditField.Value = app.dropCounter(app.indexMuscle);    % update display of counter
            
            muscleIC = [93 143 155;      % IP
                        93 112 141;      % GS
                        113 88 168;      % ST
                        107 88 140;      % ST2
                        102 128 141;     % VL
                        116 87 99;       % BFp
                        108 105 142];    % BFa
            
            % load initial conditions of muscle trials from matrix
            app.hipIC_Current = muscleIC(app.indexMuscle,1);
            app.kneeIC_Current = muscleIC(app.indexMuscle,2);
            app.ankleIC_Current = muscleIC(app.indexMuscle,3);
            
            % display the selected muscle's initial conditions
            app.hipIC.Value = app.hipIC_Current;
            app.kneeIC.Value = app.kneeIC_Current;
            app.ankleIC.Value = app.ankleIC_Current;
            
            % display the save file name
            app.DisplayFileName.Value = strcat(app.MuscleStimulatedDropDown.Value,'_trial',app.dropCounter(app.indexMuscle));
            
        end

        % Button pushed function: DropandSaveButton
        function DropandSaveButtonPushed(app, event)
            % reset data save counter
            ii = 1;
            
            % save data up to length to collect
            for ii = 1:app.lengthToCollect
                app.dataSave(ii,:) = app.anglesCurrent;
            end
            
            % save the drop data
            fileName = app.MuscleStimulatedDropDown.Value + '_trial' + app.dropCounter(app.indexMuscle);
            save(strcat('C:\GitHub\Quadruped_Robot\Code\Matlab\Analysis\DampedLeg_Krnacik\Haonan\Comparison\Trial 2\', fileName),app.dataSave,'-mat');
            app.dataSave = zeros(app.lengthToCollect,4); % clear saved data
            app.dropCounter = app.dropCounter + double(ismember(app.muscleNames,app.muscleDesired)); % increase counter for muscle dropped
            app.DropTrialNoEditField.Value = app.dropCounter(app.indexMuscle); % update display of counter
            app.DisplayFileName.Value = strcat(app.MuscleStimulatedDropDown.Value,'_trial',app.dropCounter(app.indexMuscle)); % update save file name
            
        end

        % Value changed function: LengthToCollectEditField
        function LengthToCollectEditFieldValueChanged(app, event)
            app.lengthToCollect = app.LengthToCollectEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HipAngleEditFieldLabel = uilabel(app.UIFigure);
            app.HipAngleEditFieldLabel.Text = 'Hip Angle';
            app.HipAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.KneeAngleEditFieldLabel = uilabel(app.UIFigure);
            app.KneeAngleEditFieldLabel.Text = 'Knee Angle';
            app.KneeAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnkleAngleEditFieldLabel = uilabel(app.UIFigure);
            app.AnkleAngleEditFieldLabel.Text = 'Ankle Angle';
            app.AnkleAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TareHipButton = uibutton(app.UIFigure, 'push');
            app.TareHipButton.ButtonPushedFcn = createCallbackFcn(app, @TareHipButtonPushed, true);
            app.TareHipButton.Text = 'Tare Hip';
            app.TareKneeButton = uibutton(app.UIFigure, 'push');
            app.TareKneeButton.ButtonPushedFcn = createCallbackFcn(app, @TareKneeButtonPushed, true);
            app.TareKneeButton.Text = 'Tare Knee';
            app.TareAnkleButton = uibutton(app.UIFigure, 'push');
            app.TareAnkleButton.ButtonPushedFcn = createCallbackFcn(app, @TareAnkleButtonPushed, true);
            app.TareAnkleButton.Text = 'Tare Ankle';
            app.EnterSerialPortCOMEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSerialPortCOMEditFieldLabel.Text = 'Enter Serial Port (COM)';
            app.EnterSerialPortCOMEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSerialPortCOMEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSerialPortCOMEditFieldValueChanged, true);
            app.BeginSerialCommunicationLabel = uilabel(app.UIFigure);
            app.BeginSerialCommunicationLabel.Text = 'Begin Serial Communication';
            app.COMSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.COMSelectedEditFieldLabel.Text = 'COM Selected';
            app.COMSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.CloseSerialPortLabel = uilabel(app.UIFigure);
            app.CloseSerialPortLabel.Text = 'Close Serial Port';
            app.TrialInitialConditionsLabel = uilabel(app.UIFigure);
            app.TrialInitialConditionsLabel.Text = 'Trial Initial Conditions';
            app.MuscleStimulatedDropDownLabel = uilabel(app.UIFigure);
            app.MuscleStimulatedDropDownLabel.Text = 'Muscle Stimulated';
            app.MuscleStimulatedDropDown = uidropdown(app.UIFigure);
            app.MuscleStimulatedDropDown.Items = {'IP', 'GS', 'ST', 'ST2', 'VL', 'BFp', 'BFa'};
            app.MuscleStimulatedDropDown.ValueChangedFcn = createCallbackFcn(app, @MuscleStimulatedDropDownValueChanged, true);
            app.MuscleStimulatedDropDown.Value = 'IP';
            app.hipIC = uieditfield(app.UIFigure, 'numeric');
            app.kneeIC = uieditfield(app.UIFigure, 'numeric');
            app.ankleIC = uieditfield(app.UIFigure, 'numeric');
            app.DropTrialNoEditFieldLabel = uilabel(app.UIFigure);
            app.DropTrialNoEditFieldLabel.Text = 'Drop Trial No.';
            app.DropTrialNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.DropandSaveButton = uibutton(app.UIFigure, 'push');
            app.DropandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DropandSaveButtonPushed, true);
            app.DropandSaveButton.Text = 'Drop and Save';
            app.DisplayFileName = uieditfield(app.UIFigure, 'text');
            app.LengthtoCollectEditFieldLabel = uilabel(app.UIFigure);
            app.LengthtoCollectEditFieldLabel.Text = 'Length to Collect';
            app.LengthToCollectEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthToCollectEditField.ValueChangedFcn = createCallbackFcn(app, @LengthToCollectEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_Data_Collection_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end