classdef tosionSpringTestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DPrintedTorsionSpringTesterLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        BeginSerialCommunicationLabel  matlab.ui.control.Label
        EndButton                      matlab.ui.control.Button
        CloseSerialPortLabel           matlab.ui.control.Label
        EnterSerialPortCOMEditFieldLabel  matlab.ui.control.Label
        EnterSerialPortCOMEditField    matlab.ui.control.EditField
        COMSelectedEditFieldLabel      matlab.ui.control.Label
        COMSelectedEditField           matlab.ui.control.EditField
        RangeOfMotionLabel             matlab.ui.control.Label
        TorqueResponseLabel            matlab.ui.control.Label
        SpringNameEditFieldLabel       matlab.ui.control.Label
        SpringNameEditField            matlab.ui.control.EditField
        CCWRotationdegEditFieldLabel   matlab.ui.control.Label
        CCWRotationdegEditField        matlab.ui.control.EditField
        CWRotationdegEditFieldLabel    matlab.ui.control.Label
        CWRotationdegEditField         matlab.ui.control.EditField
        ToggleCCWButton                matlab.ui.control.Button
        ToggleCWButton                 matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        CCWTorqueNmEditFieldLabel      matlab.ui.control.Label
        CCWTorqueNmEditField           matlab.ui.control.EditField
        CWTorqueNmEditFieldLabel       matlab.ui.control.Label
        CWTorqueNmEditField            matlab.ui.control.EditField
    end

    properties (Access = public)
        COMport          % COM port of Arduino
        s                % serial port object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterSerialPortCOMEditField
        function EnterSerialPortCOMEditFieldValueChanged(app, event)
            app.COMport = app.EnterSerialPortCOMEditField.Value;
            app.COMSelectedEditField.Value = app.COMport;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


            % begin serial communication with specified port and baud rat
            app.s = serialport(app.COMport, 57600);
            
            if app.s.BytesAvailable > 0
            rotation = read(app.s,4,"string");
            torque = read(app.s,4,"string");
            rotationValue = str2double(rotation);
            torqueValue = str2double(torque);
            app.CCWRotationdegEditField.Value = rotation;
            app.CCWTorqueNmEditField.Value = torque;
            end
            
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            % close serial port
            clear app.s;
        end

        % Button pushed function: ToggleCCWButton
        function ToggleCCWButtonPushed(app, event)
            write(app.s,'a',"CHAR");
        end

        % Button pushed function: ToggleCWButton
        function ToggleCWButtonPushed(app, event)
            write(app.s,'x',"CHAR");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            write(app.s,'s',"CHAR")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DPrintedTorsionSpringTesterLabel = uilabel(app.UIFigure);
            app.DPrintedTorsionSpringTesterLabel.Text = '3D Printed Torsion Spring Tester';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BeginSerialCommunicationLabel = uilabel(app.UIFigure);
            app.BeginSerialCommunicationLabel.Text = 'Begin Serial Communication';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.CloseSerialPortLabel = uilabel(app.UIFigure);
            app.CloseSerialPortLabel.Text = 'Close Serial Port';
            app.EnterSerialPortCOMEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSerialPortCOMEditFieldLabel.Text = 'Enter Serial Port (COM)';
            app.EnterSerialPortCOMEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSerialPortCOMEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSerialPortCOMEditFieldValueChanged, true);
            app.COMSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.COMSelectedEditFieldLabel.Text = 'COM Selected';
            app.COMSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.RangeOfMotionLabel = uilabel(app.UIFigure);
            app.RangeOfMotionLabel.Text = 'Range Of Motion';
            app.TorqueResponseLabel = uilabel(app.UIFigure);
            app.TorqueResponseLabel.Text = 'Torque Response';
            app.SpringNameEditFieldLabel = uilabel(app.UIFigure);
            app.SpringNameEditFieldLabel.Text = 'Spring Name';
            app.SpringNameEditField = uieditfield(app.UIFigure, 'text');
            app.CCWRotationdegEditFieldLabel = uilabel(app.UIFigure);
            app.CCWRotationdegEditFieldLabel.Text = 'CCW Rotation (deg)';
            app.CCWRotationdegEditField = uieditfield(app.UIFigure, 'text');
            app.CWRotationdegEditFieldLabel = uilabel(app.UIFigure);
            app.CWRotationdegEditFieldLabel.Text = 'CW Rotation (deg)';
            app.CWRotationdegEditField = uieditfield(app.UIFigure, 'text');
            app.ToggleCCWButton = uibutton(app.UIFigure, 'push');
            app.ToggleCCWButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleCCWButtonPushed, true);
            app.ToggleCCWButton.Text = 'Toggle CCW';
            app.ToggleCWButton = uibutton(app.UIFigure, 'push');
            app.ToggleCWButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleCWButtonPushed, true);
            app.ToggleCWButton.Text = 'Toggle CW';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.CCWTorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.CCWTorqueNmEditFieldLabel.Text = 'CCW Torque (N-m)';
            app.CCWTorqueNmEditField = uieditfield(app.UIFigure, 'text');
            app.CWTorqueNmEditFieldLabel = uilabel(app.UIFigure);
            app.CWTorqueNmEditFieldLabel.Text = 'CW Torque (N-m)';
            app.CWTorqueNmEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tosionSpringTestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end