classdef springDataCollectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TrialNoEditField               matlab.ui.control.EditField
        TrialNoEditField_2Label        matlab.ui.control.Label
        PulleyRadiusEditField          matlab.ui.control.NumericEditField
        PulleyRadiusmmLabel            matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassLabel                      matlab.ui.control.Label
        TorqueDirectionDropDown        matlab.ui.control.DropDown
        TorqueDirectionDropDownLabel   matlab.ui.control.Label
        LengthToCollectEditField       matlab.ui.control.NumericEditField
        LengthtoCollectEditFieldLabel  matlab.ui.control.Label
        DisplayFileName                matlab.ui.control.EditField
        SaveData                       matlab.ui.control.Button
        SpringNameDropDown             matlab.ui.control.DropDown
        SpringNameDropDownLabel        matlab.ui.control.Label
        CloseSerialPortLabel           matlab.ui.control.Label
        EndButton                      matlab.ui.control.Button
        COMSelectedEditField           matlab.ui.control.EditField
        COMSelectedEditFieldLabel      matlab.ui.control.Label
        BeginSerialCommunicationLabel  matlab.ui.control.Label
        EnterSerialPortCOMEditField    matlab.ui.control.EditField
        EnterSerialPortCOMEditFieldLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        COMport             % COM port of Arduino
        s                   % serial port object
        angleText           % text output of encoders
        angleCurrent        % actual encoder angle readings
        lengthToCollect     % number of data points to save for each drop trial
        dataSave            % matrix to store data for each drop trial
        springSelected      % currently selected spring from drop down
        direction           % direction of applied torque CW or CCW
        trial               % current trial number
        mass                % mass hanging from pulley
        radius              % pulley radius
        torque              % torque applied to spring
        torqueText          % torque as a string

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % begin serial communication with specified port and baud rate
            app.s = serialport(app.COMport, 115200);
           
            % default 1000 data points to collect per trial
            app.lengthToCollect = 1000;
            app.LengthToCollectEditField.Value = app.lengthToCollect;
            
            % initialize data save matrix
            app.dataSave = zeros(app.lengthToCollect,1);
            
            % initialize spring name 
            app.springSelected = app.SpringNameDropDown.Value;
            
            % initialize direction 
            app.direction = app.TorqueDirectionDropDown.Value;
            
            % initialize trial number 
            app.trial = app.TrialNoEditField.Value;
            
            % initialize mass 
            app.mass = app.MassEditField.Value;
            
            % initialize pulley radius 
            app.radius = app.PulleyRadiusEditField.Value;
            
            % initialize torque
            app.torque = (app.mass/1000)*9.81*(app.radius);
            
            app.torqueText = num2str(app.torque,3);
            
            % add path to folder for saving results
            addpath('E:\GitHub\Quadruped_Robot\Code\Matlab\Analysis\DampedLeg_Zheng\3DPrinted_Torsion_Spring\801angdispltestresults\');
            
            % data streamed from Arduino will be in format:
            % [encoder angle(degrees) time(ms)]
        end

        % Value changed function: EnterSerialPortCOMEditField
        function EnterSerialPortCOMEditFieldValueChanged(app, event)
            app.COMport = app.EnterSerialPortCOMEditField.Value;
            app.COMSelectedEditField.Value = app.COMport;
            
        end

        % Callback function
        function EndButtonPushed(app, event)
            app.readContinuous = 2;         % stop reading serial data
            clear app.s;                    % close serial port
            
        end

        % Value changed function: SpringNameDropDown
        function SpringNameDropDownValueChanged(app, event)
            app.springSelected = app.SpringNameDropDown.Value;     % select muscle trial to evaluate from dropdown menu
            % display the save file name
            app.DisplayFileName.Value = strcat(app.springSelected,'_',app.torqueText,'Nmm_',app.direction,'_trial',app.trial);
            
        end

        % Button pushed function: SaveData
        function SaveDataPushed(app, event)
            %Determine how many lines of data to collect, and initialize a cell array
            %to store in the data from the serial port
            flush(app.s);
            dataText = cell(app.lengthToCollect,1);

            % Read the data and store in a matrix
            % Read all the data (ASCII) into a cell array
            for ii = 1:app.lengthToCollect+1  %First read almost always starts in the middle of a number (Or is 'arduino is ready'), so it is likely not useable, so add 1
            dataText{ii} = readline(app.s);
            end

            %Convert the data from ASCII to numbers
            a = 2; %initialize counting variable (first read is always 'junk')
            b = 1; %initialize storing variable
            while a <= app.lengthToCollect + 1 %While a is less than or equal to the amount of data we need to collect
                if(~isempty(str2double(dataText{a})))  %Make sure the data we are reading is actually a number
                    app.dataSave(b,:) = str2double(dataText{a});  %If it is a number, store it
                    b = b+1;  %Increment storage variable
                end
                a = a+1;   %Increment read variable
            end

            % save data up to length to collect
            data = app.dataSave;
            torque = app.torque;
            
            plot(app.UIAxes,data);
            
            % save the drop data
            save(strcat('E:\GitHub\Quadruped_Robot\Code\Matlab\Analysis\DampedLeg_Zheng\3DPrinted_Torsion_Spring\801angdispltestresults\',app.DisplayFileName.Value,'.mat'),"data","torque",'-mat');
            app.dataSave = zeros(app.lengthToCollect,1); % clear saved data
            clear dataText;
            
            
        end

        % Value changed function: LengthToCollectEditField
        function LengthToCollectEditFieldValueChanged(app, event)
            app.lengthToCollect = app.LengthToCollectEditField.Value;
            app.dataSave = zeros(app.lengthToCollect,1);
        end

        % Value changed function: TorqueDirectionDropDown
        function TorqueDirectionDropDownValueChanged(app, event)
            app.direction = app.TorqueDirectionDropDown.Value;
            app.DisplayFileName.Value = strcat(app.springSelected,'_',app.torqueText,'Nmm_',app.direction,'_trial',app.trial);            
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.mass = app.MassEditField.Value;
            app.torque = (app.mass/1000)*9.81*(app.radius);
            app.torqueText = num2str(app.torque,3);
            
            app.DisplayFileName.Value = strcat(app.springSelected,'_',app.torqueText,'Nmm_',app.direction,'_trial',app.trial);
        end

        % Value changed function: PulleyRadiusEditField
        function PulleyRadiusEditFieldValueChanged(app, event)
            app.radius = app.PulleyRadiusEditField.Value;
            app.torque = (app.mass/1000)*9.81*(app.radius);
            app.torqueText = num2str(app.torque,3);
            
            app.DisplayFileName.Value = strcat(app.springSelected,'_',app.torqueText,'Nmm_',app.direction,'_trial',app.trial);
        end

        % Value changed function: TrialNoEditField
        function TrialNoEditFieldValueChanged(app, event)
            app.trial = app.TrialNoEditField.Value;
            app.DisplayFileName.Value = strcat(app.springSelected,'_',app.torqueText,'Nmm_',app.direction,'_trial',app.trial);               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spring Test Preview')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Angluar Displacement (º)')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EnterSerialPortCOMEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSerialPortCOMEditFieldLabel.Text = 'Enter Serial Port (COM)';
            app.EnterSerialPortCOMEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSerialPortCOMEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSerialPortCOMEditFieldValueChanged, true);
            app.BeginSerialCommunicationLabel = uilabel(app.UIFigure);
            app.BeginSerialCommunicationLabel.Text = 'Begin Serial Communication';
            app.COMSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.COMSelectedEditFieldLabel.Text = 'COM Selected';
            app.COMSelectedEditField = uieditfield(app.UIFigure, 'text');
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.Text = 'End';
            app.CloseSerialPortLabel = uilabel(app.UIFigure);
            app.CloseSerialPortLabel.Text = 'Close Serial Port';
            app.SpringNameDropDownLabel = uilabel(app.UIFigure);
            app.SpringNameDropDownLabel.Text = 'Spring Name';
            app.SpringNameDropDown = uidropdown(app.UIFigure);
            app.SpringNameDropDown.Items = {'2L2LT4ST_37T', '2L3LT4ST_37T', '2L4LT4ST_37T', '2L5LT2ST_37T', '2L5LT4ST_37T', '2L5LT8ST_37T', '2L5LT12ST_37T', '2L2LT4ST_100I', '2L3LT4ST_100I', '2L4LT4ST_100I', '2L5LT2ST_100I', '2L5LT4ST_100I', '2L5LT8ST_100I', '2L5LT12ST_100I', '2L4.5LT6ST_100I'};
            app.SpringNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SpringNameDropDownValueChanged, true);
            app.SpringNameDropDown.Value = '2L2LT4ST_37T';
            app.SaveData = uibutton(app.UIFigure, 'push');
            app.SaveData.ButtonPushedFcn = createCallbackFcn(app, @SaveDataPushed, true);
            app.SaveData.Text = 'Save Data';
            app.DisplayFileName = uieditfield(app.UIFigure, 'text');
            app.LengthtoCollectEditFieldLabel = uilabel(app.UIFigure);
            app.LengthtoCollectEditFieldLabel.Text = 'Length to Collect';
            app.LengthToCollectEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthToCollectEditField.ValueDisplayFormat = '%11.6g';
            app.LengthToCollectEditField.ValueChangedFcn = createCallbackFcn(app, @LengthToCollectEditFieldValueChanged, true);
            app.TorqueDirectionDropDownLabel = uilabel(app.UIFigure);
            app.TorqueDirectionDropDownLabel.Text = 'Torque Direction';
            app.TorqueDirectionDropDown = uidropdown(app.UIFigure);
            app.TorqueDirectionDropDown.Items = {'CW', 'CCW'};
            app.TorqueDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @TorqueDirectionDropDownValueChanged, true);
            app.TorqueDirectionDropDown.Value = 'CW';
            app.MassLabel = uilabel(app.UIFigure);
            app.MassLabel.Text = 'Mass (g)';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.PulleyRadiusmmLabel = uilabel(app.UIFigure);
            app.PulleyRadiusmmLabel.Text = 'Pulley Radius (mm)';
            app.PulleyRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulleyRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @PulleyRadiusEditFieldValueChanged, true);
            app.TrialNoEditField_2Label = uilabel(app.UIFigure);
            app.TrialNoEditField_2Label.Text = 'Trial No';
            app.TrialNoEditField = uieditfield(app.UIFigure, 'text');
            app.TrialNoEditField.ValueChangedFcn = createCallbackFcn(app, @TrialNoEditFieldValueChanged, true);
            app.TrialNoEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = springDataCollectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end