classdef Simple_Muscle_Control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleMuscleControlAppUIFigure  matlab.ui.Figure
        HIPSwitchLabel                  matlab.ui.control.Label
        FrontLeftHipExtensorSwitch      matlab.ui.control.Switch
        FrontLeftHipExtensorGreenLamp   matlab.ui.control.Lamp
        FrontLeftHipFlexorGreenLamp     matlab.ui.control.Lamp
        HIPSwitch_3Label                matlab.ui.control.Label
        FrontLeftHipFlexorSwitch        matlab.ui.control.Switch
        FrontLeftHipFlexorRedLamp       matlab.ui.control.Lamp
        FrontLeftHipExtensorRedLamp     matlab.ui.control.Lamp
        FrontLeftKneeExtensorGreenLamp  matlab.ui.control.Lamp
        FrontLeftKneeExtensorRedLamp    matlab.ui.control.Lamp
        KNEESwitchLabel                 matlab.ui.control.Label
        FrontLeftKneeExtensorSwitch     matlab.ui.control.Switch
        FrontLeftKneeFlexorGreenLamp    matlab.ui.control.Lamp
        FrontLeftKneeFlexorRedLamp      matlab.ui.control.Lamp
        KNEESwitch_3Label               matlab.ui.control.Label
        FrontLeftKneeFlexorSwitch       matlab.ui.control.Switch
        FrontLeftAnkleExtensorGreenLamp  matlab.ui.control.Lamp
        FrontLeftAnkleExtensorRedLamp   matlab.ui.control.Lamp
        ANKLESwitchLabel                matlab.ui.control.Label
        FrontLeftAnkleExtensorSwitch    matlab.ui.control.Switch
        FrontLeftAnkleFlexorGreenLamp   matlab.ui.control.Lamp
        FrontLeftAnkleFlexorRedLamp     matlab.ui.control.Lamp
        ANKLESwitch_3Label              matlab.ui.control.Label
        FrontLeftAnkleFlexorSwitch      matlab.ui.control.Switch
        BackLeftHipExtensorGreenLamp    matlab.ui.control.Lamp
        BackLeftHipFlexorGreenLamp      matlab.ui.control.Lamp
        BackLeftHipFlexorRedLamp        matlab.ui.control.Lamp
        BackLeftHipExtensorRedLamp      matlab.ui.control.Lamp
        BackLeftKneeExtensorGreenLamp   matlab.ui.control.Lamp
        BackLeftKneeExtensorRedLamp     matlab.ui.control.Lamp
        BackLeftKneeFlexorGreenLamp     matlab.ui.control.Lamp
        BackLeftKneeFlexorRedLamp       matlab.ui.control.Lamp
        BackLeftAnkleExtensorGreenLamp  matlab.ui.control.Lamp
        BackLeftAnkleExtensorRedLamp    matlab.ui.control.Lamp
        BackLeftAnkleFlexorGreenLamp    matlab.ui.control.Lamp
        BackLeftAnkleFlexorRedLamp      matlab.ui.control.Lamp
        HIPSwitch_2Label                matlab.ui.control.Label
        BackLeftHipExtensorSwitch       matlab.ui.control.Switch
        HIPSwitch_4Label                matlab.ui.control.Label
        BackLeftHipFlexorSwitch         matlab.ui.control.Switch
        KNEESwitch_2Label               matlab.ui.control.Label
        BackLeftKneeExtensorSwitch      matlab.ui.control.Switch
        KNEESwitch_4Label               matlab.ui.control.Label
        BackLeftKneeFlexorSwitch        matlab.ui.control.Switch
        ANKLESwitch_2Label              matlab.ui.control.Label
        BackLeftAnkleExtensorSwitch     matlab.ui.control.Switch
        ANKLESwitch_4Label              matlab.ui.control.Label
        BackLeftAnkleFlexorSwitch       matlab.ui.control.Switch
        EXTENSORSLabel_Left             matlab.ui.control.Label
        FLEXORSLabel_Left               matlab.ui.control.Label
        FRONTLabel                      matlab.ui.control.Label
        BACKLabel                       matlab.ui.control.Label
        MusclePressureAxes              matlab.ui.control.UIAxes
        LEFTLabel                       matlab.ui.control.Label
        EXTENSORSLabel_Right            matlab.ui.control.Label
        HIPSwitch_5Label                matlab.ui.control.Label
        FrontRightHipExtensorSwitch     matlab.ui.control.Switch
        FrontRightHipExtensorGreenLamp  matlab.ui.control.Lamp
        FrontRightHipExtensorRedLamp    matlab.ui.control.Lamp
        KNEESwitch_5Label               matlab.ui.control.Label
        FrontRightKneeExtensorSwitch    matlab.ui.control.Switch
        FrontRightKneeExtensorGreenLamp  matlab.ui.control.Lamp
        FrontRightKneeExtensorRedLamp   matlab.ui.control.Lamp
        ANKLESwitch_5Label              matlab.ui.control.Label
        FrontRightAnkleExtensorSwitch   matlab.ui.control.Switch
        FrontRightAnkleExtensorGreenLamp  matlab.ui.control.Lamp
        FrontRightAnkleExtensorRedLamp  matlab.ui.control.Lamp
        HIPSwitch_6Label                matlab.ui.control.Label
        BackRightHipExtensorSwitch      matlab.ui.control.Switch
        BackRightHipExtensorGreenLamp   matlab.ui.control.Lamp
        BackRightHipExtensorRedLamp     matlab.ui.control.Lamp
        KNEESwitch_6Label               matlab.ui.control.Label
        BackRightKneeExtensorSwitch     matlab.ui.control.Switch
        BackRightKneeExtensorGreenLamp  matlab.ui.control.Lamp
        BackRightKneeExtensorRedLamp    matlab.ui.control.Lamp
        ANKLESwitch_6Label              matlab.ui.control.Label
        BackRightAnkleExtensorSwitch    matlab.ui.control.Switch
        BackRightAnkleExtensorGreenLamp  matlab.ui.control.Lamp
        BackRightAnkleExtensorRedLamp   matlab.ui.control.Lamp
        FLEXORSLabel_Right              matlab.ui.control.Label
        HIPSwitch_7Label                matlab.ui.control.Label
        FrontRightHipFlexorSwitch       matlab.ui.control.Switch
        FrontRightHipFlexorGreenLamp    matlab.ui.control.Lamp
        FrontRightHipFlexorRedLamp      matlab.ui.control.Lamp
        KNEESwitch_7Label               matlab.ui.control.Label
        FrontRightKneeFlexorSwitch      matlab.ui.control.Switch
        FrontRightKneeFlexorGreenLamp   matlab.ui.control.Lamp
        FrontRightKneeFlexorRedLamp     matlab.ui.control.Lamp
        ANKLESwitch_7Label              matlab.ui.control.Label
        FrontRightAnkleFlexorSwitch     matlab.ui.control.Switch
        FrontRightAnkleFlexorGreenLamp  matlab.ui.control.Lamp
        FrontRightAnkleFlexorRedLamp    matlab.ui.control.Lamp
        HIPSwitch_8Label                matlab.ui.control.Label
        BackRightHipFlexorSwitch        matlab.ui.control.Switch
        BackRightHipFlexorGreenLamp     matlab.ui.control.Lamp
        BackRightHipFlexorRedLamp       matlab.ui.control.Lamp
        KNEESwitch_8Label               matlab.ui.control.Label
        BackRightKneeFlexorSwitch       matlab.ui.control.Switch
        BackRightKneeFlexorGreenLamp    matlab.ui.control.Lamp
        BackRightKneeFlexorRedLamp      matlab.ui.control.Lamp
        ANKLESwitch_8Label              matlab.ui.control.Label
        BackRightAnkleFlexorSwitch      matlab.ui.control.Switch
        BackRightAnkleFlexorGreenLamp   matlab.ui.control.Lamp
        BackRightAnkleFlexorRedLamp     matlab.ui.control.Lamp
        RIGHTLabel                      matlab.ui.control.Label
        JointAngleAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        %Define the front left pressure sensor and joint angle conversion factors.
        front_left_hip_extensor_pressure_conversion = 5/862;			%[V/#] Bit to voltage conversion factor for front left hip extensor pressure readings.
        front_left_hip_flexor_pressure_conversion = 5/841;			%[V/#] Bit to voltage conversion factor for front left hip flexor pressure readings.
        front_left_hip_angle_conversion = 5/705;				%[V/#] Bit to voltage conversion factor for front left hip joint angle readings.
        
        front_left_knee_extensor_pressure_conversion = 5/838;			%[V/#] Bit to voltage conversion factor for front left knee extensor pressure readings.
        front_left_knee_flexor_pressure_conversion = 5/837;			%[V/#] Bit to voltage conversion factor for front left knee flexor pressure readings.
        front_left_knee1_angle_conversion = 5/802;				%[V/#] Bit to voltage conversion factor for front left knee1 joint angle readings.
        front_left_knee2_angle_conversion = 5/813;				%[V/#] Bit to voltage conversion factor for front left knee2 joint angle readings.

        front_left_ankle_extensor_pressure_conversion = 5/822;			%[V/#] Bit to voltage conversion factor for front left ankle extensor pressure readings.
        front_left_ankle_flexor_pressure_conversion = 5/847;			%[V/#] Bit to voltage conversion factor for front left ankle flexor pressure readings.
        front_left_ankle_angle_conversion = 5/968;				%[V/#] Bit to voltage conversion factor for front left ankle joint angle readings.
        
        %Define the back left pressure sensor and joint angle conversion factors.
        back_left_hip_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back left hip extensor pressure readings.
        back_left_hip_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back left hip flexor pressure readings.
        back_left_hip_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for back left hip joint angle readings.
        
        back_left_knee_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back left knee extensor pressure readings.
        back_left_knee_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back left knee flexor pressure readings.
        back_left_knee_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for back left knee joint angle readings.

        back_left_ankle_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back left ankle extensor pressure readings.
        back_left_ankle_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back left ankle flexor pressure readings.
        back_left_ankle_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for back left ankle joint angle readings.
        
        %Define the front right pressure sensor and joint angle conversion factors.
        front_right_hip_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for front right hip extensor pressure readings.
        front_right_hip_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for front right hip flexor pressure readings.
        front_right_hip_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for front right hip joint angle readings.
        
        front_right_knee_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for front right knee extensor pressure readings.
        front_right_knee_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for front right knee flexor pressure readings.
        front_right_knee1_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for front right knee1 joint angle readings.
        front_right_knee2_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for front right knee2 joint angle readings.

        front_right_ankle_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for front right ankle extensor pressure readings.
        front_right_ankle_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for front right ankle flexor pressure readings.
        front_right_ankle_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for front right ankle joint angle readings.
        
        %Define the back right pressure sensor and joint angle conversion factors.
        back_right_hip_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back right hip extensor pressure readings.
        back_right_hip_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back right hip flexor pressure readings.
        back_right_hip_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for back right hip joint angle readings.
        
        back_right_knee_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back right knee extensor pressure readings.
        back_right_knee_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back right knee flexor pressure readings.
        back_right_knee_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for back right knee joint angle readings.

        back_right_ankle_extensor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back right ankle extensor pressure readings.
        back_right_ankle_flexor_pressure_conversion = 5/1023;			%[V/#] Bit to voltage conversion factor for back right ankle flexor pressure readings.
        back_right_ankle_angle_conversion = 5/1023;				%[V/#] Bit to voltage conversion factor for back right ankle joint angle readings.
        
        
        %Define the front left pressure sensor and joint angle offsets.
        front_left_hip_extensor_pressure_offset = 55;			        %[#] Bit offset for front left hip extensor pressure sensor.
        front_left_hip_flexor_pressure_offset = 55;				%[#] Bit offset for front left hip flexor pressure sensor.
        front_left_hip_angle_offset = 55;					%[#] Bit offset for front left hip potentiometer.
        
        front_left_knee_extensor_pressure_offset = 55;			        %[#] Bit offset for front left knee extensor pressure sensor.
        front_left_knee_flexor_pressure_offset = 55;				%[#] Bit offset for front left knee flexor pressure sensor.
        front_left_knee1_angle_offset = 55;					%[#] Bit offset for front left knee1 potentiometer.
        front_left_knee2_angle_offset = 55;					%[#] Bit offset for front left knee1 potentiometer.
        
        front_left_ankle_extensor_pressure_offset = 55;			        %[#] Bit offset for front left ankle extensor pressure sensor.
        front_left_ankle_flexor_pressure_offset = 55;				%[#] Bit offset for front left ankle flexor pressure sensor.
        front_left_ankle_angle_offset = 55;					%[#] Bit offset for front left ankle potentiometer.
        
        %Define the back left pressure sensor and joint angle offsets.
        back_left_hip_extensor_pressure_offset = 55;			        %[#] Bit offset for back left hip extensor pressure sensor.
        back_left_hip_flexor_pressure_offset = 55;				%[#] Bit offset for back left hip flexor pressure sensor.
        back_left_hip_angle_offset = 0;					        %[#] Bit offset for back left hip potentiometer.
        
        back_left_knee_extensor_pressure_offset = 55;			        %[#] Bit offset for back left knee extensor pressure sensor.
        back_left_knee_flexor_pressure_offset = 55;				%[#] Bit offset for back left knee flexor pressure sensor.
        back_left_knee_angle_offset = 0;					%[#] Bit offset for back left knee potentiometer.
        
        back_left_ankle_extensor_pressure_offset = 55;			        %[#] Bit offset for back left ankle extensor pressure sensor.
        back_left_ankle_flexor_pressure_offset = 55;				%[#] Bit offset for back left ankle flexor pressure sensor.
        back_left_ankle_angle_offset = 0;					%[#] Bit offset for back left ankle potentiometer.
        
        %Define the front right pressure sensor and joint angle offsets.
        front_right_hip_extensor_pressure_offset = 55;			        %[#] Bit offset for front right hip extensor pressure sensor.
        front_right_hip_flexor_pressure_offset = 55;				%[#] Bit offset for front right hip flexor pressure sensor.
        front_right_hip_angle_offset = 0;					%[#] Bit offset for front right hip potentiometer.
        
        front_right_knee_extensor_pressure_offset = 55;			        %[#] Bit offset for front right knee extensor pressure sensor.
        front_right_knee_flexor_pressure_offset = 55;				%[#] Bit offset for front right knee flexor pressure sensor.
        front_right_knee1_angle_offset = 0;					%[#] Bit offset for front right knee1 potentiometer.
        front_right_knee2_angle_offset = 0;					%[#] Bit offset for front right knee1 potentiometer.
        
        front_right_ankle_extensor_pressure_offset = 55;			        %[#] Bit offset for front right ankle extensor pressure sensor.
        front_right_ankle_flexor_pressure_offset = 55;				%[#] Bit offset for front right ankle flexor pressure sensor.
        front_right_ankle_angle_offset = 0;					%[#] Bit offset for front right ankle potentiometer.
        
        %Define the back right pressure sensor and joint angle offsets.
        back_right_hip_extensor_pressure_offset = 55;			        %[#] Bit offset for back right hip extensor pressure sensor.
        back_right_hip_flexor_pressure_offset = 55;				%[#] Bit offset for back right hip flexor pressure sensor.
        back_right_hip_angle_offset = 0;					%[#] Bit offset for back right hip potentiometer.
        
        back_right_knee_extensor_pressure_offset = 55;			        %[#] Bit offset for back right knee extensor pressure sensor.
        back_right_knee_flexor_pressure_offset = 55;				%[#] Bit offset for back right knee flexor pressure sensor.
        back_right_knee_angle_offset = 0;					%[#] Bit offset for back right knee potentiometer.
        
        back_right_ankle_extensor_pressure_offset = 55;			        %[#] Bit offset for back right ankle extensor pressure sensor.
        back_right_ankle_flexor_pressure_offset = 55;				%[#] Bit offset for back right ankle flexor pressure sensor.
        back_right_ankle_angle_offset = 0;					%[#] Bit offset for back right ankle potentiometer.
        
    end
    
    methods (Access = private)
        
        function SetLampStatus(app, SwitchObj, GreenLampObj, RedLampObj)
            %Retrieve the state of the switch.
            switch_state = SwitchObj.Value;
            
            %Turn on or off the associated lamp.
            if strcmp(switch_state, 'On')
                GreenLampObj.Enable = 'On';
                RedLampObj.Enable = 'Off';
            else
                GreenLampObj.Enable = 'Off';
                RedLampObj.Enable = 'On';
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            %Open the serial port.
            serial_port = OpenAtmegaSerialPort( 'COM6', 9600 );
            
            %Set the window size for serial port communication.
            window_size = 2;
            
            %Define the number of points to keep on the plot.
            num_points_to_keep = 250;
            
            %Define the pressure and joint angle conversion factors.
            pressure_conversion_factors = [app.front_left_hip_extensor_pressure_conversion, app.front_left_hip_flexor_pressure_conversion, ...
                                           app.front_left_knee_extensor_pressure_conversion, app.front_left_knee_flexor_pressure_conversion, ...
                                           app.front_left_ankle_extensor_pressure_conversion, app.front_left_ankle_flexor_pressure_conversion, ...
                                           app.back_left_hip_extensor_pressure_conversion, app.back_left_hip_flexor_pressure_conversion, ...
                                           app.back_left_knee_extensor_pressure_conversion, app.back_left_knee_flexor_pressure_conversion, ...
                                           app.back_left_ankle_extensor_pressure_conversion, app.back_left_ankle_flexor_pressure_conversion, ...
                                           app.front_right_hip_extensor_pressure_conversion, app.front_right_hip_flexor_pressure_conversion, ...
                                           app.front_right_knee_extensor_pressure_conversion, app.front_right_knee_flexor_pressure_conversion, ...
                                           app.front_right_ankle_extensor_pressure_conversion, app.front_right_ankle_flexor_pressure_conversion, ...
                                           app.back_right_hip_extensor_pressure_conversion, app.back_right_hip_flexor_pressure_conversion, ...
                                           app.back_right_knee_extensor_pressure_conversion, app.back_right_knee_flexor_pressure_conversion, ...
                                           app.back_right_ankle_extensor_pressure_conversion, app.back_right_ankle_flexor_pressure_conversion];
                                       
            joint_angle_conversion_factors = [app.front_left_hip_angle_conversion, app.front_left_knee1_angle_conversion, app.front_left_knee2_angle_conversion, app.front_left_ankle_angle_conversion, ...
                                              app.back_left_hip_angle_conversion, app.back_left_knee_angle_conversion, app.back_left_ankle_angle_conversion, ...
                                              app.front_right_hip_angle_conversion, app.front_right_knee1_angle_conversion, app.front_right_knee2_angle_conversion, app.front_right_ankle_angle_conversion, ...
                                              app.back_right_hip_angle_conversion, app.back_right_knee_angle_conversion, app.back_right_ankle_angle_conversion];
            
            %Define the pressure and joint angle offset values.
            pressure_offsets = [app.front_left_hip_extensor_pressure_offset, app.front_left_hip_flexor_pressure_offset, ...
                                app.front_left_knee_extensor_pressure_offset, app.front_left_knee_flexor_pressure_offset, ...
                                app.front_left_ankle_extensor_pressure_offset, app.front_left_ankle_flexor_pressure_offset, ...
                                app.back_left_hip_extensor_pressure_offset, app.back_left_hip_flexor_pressure_offset, ...
                                app.back_left_knee_extensor_pressure_offset, app.back_left_knee_flexor_pressure_offset, ...
                                app.back_left_ankle_extensor_pressure_offset, app.back_left_ankle_flexor_pressure_offset, ...
                                app.front_right_hip_extensor_pressure_offset, app.front_right_hip_flexor_pressure_offset, ...
                                app.front_right_knee_extensor_pressure_offset, app.front_right_knee_flexor_pressure_offset, ...
                                app.front_right_ankle_extensor_pressure_offset, app.front_right_ankle_flexor_pressure_offset, ...
                                app.back_right_hip_extensor_pressure_offset, app.back_right_hip_flexor_pressure_offset, ...
                                app.back_right_knee_extensor_pressure_offset, app.back_right_knee_flexor_pressure_offset, ...
                                app.back_right_ankle_extensor_pressure_offset, app.back_right_ankle_flexor_pressure_offset];
                            
            joint_angle_offsets = [app.front_left_hip_angle_offset, app.front_left_knee1_angle_offset, app.front_left_knee2_angle_offset, app.front_left_ankle_angle_offset, ...
                                   app.back_left_hip_angle_offset, app.back_left_knee_angle_offset, app.back_left_ankle_angle_offset, ...
                                   app.front_right_hip_angle_offset, app.front_right_knee1_angle_offset, app.front_right_knee2_angle_offset, app.front_right_ankle_angle_offset, ...
                                   app.back_right_hip_angle_offset, app.back_right_knee_angle_offset, app.back_right_ankle_angle_offset];
            
            %Preallocate arrays to store the pressure and joint angle data.
            ts = 0; [ps_adc, ps_volt] = deal( zeros(24, num_points_to_keep) ); [thetas_adc, thetas_volt] = deal( zeros(14, num_points_to_keep) );
            
            
            %Preallocate the individual muscle pressure and joint angle variables.
            [ps_front_left_hip_extensor, ps_front_left_hip_flexor, ps_front_left_knee_extensor, ps_front_left_knee_flexor, ps_front_left_ankle_extensor, ps_front_left_ankle_flexor] = deal( 0 );
            [thetas_front_left_hip, thetas_front_left_knee1, thetas_front_left_knee2, thetas_front_left_ankle] = deal( 0 );
            
            %Setup the muscle pressure plot.
            hold(app.MusclePressureAxes, 'on'), axis(app.MusclePressureAxes, [0 num_points_to_keep 0 5.2]), set(app.MusclePressureAxes, 'XTickMode', 'auto', 'YTickMode', 'auto', 'PlotBoxAspectRatioMode', 'auto', 'DataAspectRatioMode', 'auto', 'XTickLabelMode', 'auto', 'YTickLabelMode', 'auto')
            
            %Create the starting muscle pressure line elements.
            h_front_left_hip_extensor = plot(app.MusclePressureAxes, ts, ps_front_left_hip_extensor, '-', 'XDataSource', 'ts', 'YDataSource', 'ps_front_left_hip_extensor');
            h_front_left_hip_flexor = plot(app.MusclePressureAxes, ts, ps_front_left_hip_flexor, '-', 'XDataSource', 'ts', 'YDataSource', 'ps_front_left_hip_flexor');
            h_front_left_knee_extensor = plot(app.MusclePressureAxes, ts, ps_front_left_knee_extensor, '-', 'XDataSource', 'ts', 'YDataSource', 'ps_front_left_knee_extensor');
            h_front_left_knee_flexor = plot(app.MusclePressureAxes, ts, ps_front_left_knee_flexor, '-', 'XDataSource', 'ts', 'YDataSource', 'ps_front_left_knee_flexor');
            h_front_left_ankle_extensor = plot(app.MusclePressureAxes, ts, ps_front_left_ankle_extensor, '-', 'XDataSource', 'ts', 'YDataSource', 'ps_front_left_ankle_extensor');
            h_front_left_ankle_flexor = plot(app.MusclePressureAxes, ts, ps_front_left_ankle_flexor, '-', 'XDataSource', 'ts', 'YDataSource', 'ps_front_left_ankle_flexor');
            
            %Store the muscle pressure line elements into an array.
            h_muscle_pressures = [h_front_left_hip_extensor, h_front_left_hip_flexor, h_front_left_knee_extensor, h_front_left_knee_flexor, h_front_left_ankle_extensor, h_front_left_ankle_flexor];
            
            %Create a legend for the muscle pressure plot.
            leg_str_muscle_pressures = {'Front Left Hip Extensor', 'Front Left Hip Flexor', 'Front Left Knee Extensor', 'Front Left Knee Flexor', 'Front Left Ankle Extensor', 'Front Left Ankle Flexor'};
            legend(app.MusclePressureAxes, h_muscle_pressures, leg_str_muscle_pressures, 'Location', 'SouthOutside', 'Orientation', 'Vertical')
            %columnlegend(2, leg_str_muscle_pressures, 'Location', 'SouthOutside')
            
            %Setup the joint angle plot.
            hold(app.JointAngleAxes, 'on'), axis(app.JointAngleAxes, [0 num_points_to_keep 0 5.2]), set(app.JointAngleAxes, 'XTickMode', 'auto', 'YTickMode', 'auto', 'PlotBoxAspectRatioMode', 'auto', 'DataAspectRatioMode', 'auto', 'XTickLabelMode', 'auto', 'YTickLabelMode', 'auto')
            
            %Create the strting joint angle line elements.
            h_front_left_hip_angle = plot(app.JointAngleAxes, ts, thetas_front_left_hip, '-', 'XDataSource', 'ts', 'YDataSource', 'thetas_front_left_hip');
            h_front_left_knee1_angle = plot(app.JointAngleAxes, ts, thetas_front_left_knee1, '-', 'XDataSource', 'ts', 'YDataSource', 'thetas_front_left_knee1');
            h_front_left_knee2_angle = plot(app.JointAngleAxes, ts, thetas_front_left_knee2, '-', 'XDataSource', 'ts', 'YDataSource', 'thetas_front_left_knee2');
            h_front_left_ankle_angle = plot(app.JointAngleAxes, ts, thetas_front_left_ankle, '-', 'XDataSource', 'ts', 'YDataSource', 'thetas_front_left_ankle');
            
            %Store the joint angle line elements into an array.
            h_joint_angles = [h_front_left_hip_angle, h_front_left_knee1_angle, h_front_left_knee2_angle, h_front_left_ankle_angle];
            
            %Create a legend of the joint angle plot.
            leg_str_joint_angles = {'Front Left Hip Angle', 'Front Left Knee1 Angle', 'Front Left Knee2 Angle', 'Front Left Ankle Angle'};
            legend(app.JointAngleAxes, h_joint_angles, leg_str_joint_angles, 'Location', 'SouthOutside', 'Orientation', 'Vertical')
            
            %Initialize counters for the loop.
            loc = 0;                        %Define a counter for the pressure sensor data array position.
            count = 0;                      %Loop counter.
            
            %Main loop to execute.
            while true                         %Iterate indefinitely....
                
                %Advance the counter.
                count = count + 1;
                
                %Determine where to store the next pressure or angle value.
                if loc < num_points_to_keep                        %If the number of pressure points is still less than the maximum...
                    loc = loc + 1;                                 %Advance the counter to put the pressure in the next open spot.
                    ts = 1:loc;                                    %Update the time vector for plotting.
                else
                    
                    %Update the timer vector for plotting.
                    ts = ts + 1;
                    
                    %Shift the pressure data over.
                    ps_adc = [ps_adc(:, 2:end) zeros(size(ps_adc, 1), 1)];
                    ps_volt = [ps_volt(:, 2:end) zeros(size(ps_volt, 1), 1)];
                    
                    %Shift the angle data over.
                    thetas_adc = [thetas_adc(:, 2:end) zeros(size(thetas_adc, 1), 1)];
                    thetas_volt = [thetas_volt(:, 2:end) zeros(size(thetas_volt, 1), 1)];
            
                    %Shift the xlims of the plots.
                    xlim(app.MusclePressureAxes, [ts(1) ts(end)])
                    xlim(app.JointAngleAxes, [ts(1) ts(end)])
                    
                end
                
                %Retrieve the switch values.
                switch_values = {app.FrontLeftHipExtensorSwitch.Value, app.FrontLeftHipFlexorSwitch.Value, app.FrontLeftKneeExtensorSwitch.Value, app.FrontLeftKneeFlexorSwitch.Value, app.FrontLeftAnkleExtensorSwitch.Value, app.FrontLeftAnkleFlexorSwitch.Value};
                control_values_n = strcmp(switch_values, 'On');
                
                %Write the switch values to the microcontroller.
                serial_write_int_array2micro( serial_port, control_values_n )
                
                %Read in the pressure sensor data.
                pressure_data_n = serial_read_micro_int_array( serial_port, window_size );
                
                %Read in joint angle data.
            %                 joint_data_n = serial_read_micro_int( serial_port, window_size );
                joint_data_n = serial_read_micro_int_array( serial_port, window_size );
            
                %Add the new pressure data to the existing pressure data matrix for plotting.
                ps_adc(:, loc) = pressure_data_n';
                ps_volt(:, loc) = (pressure_conversion_factors.*(pressure_data_n - pressure_offsets))';
                
                %Add the new joint angle data to the existing joint angle data matrix for plotting.
                thetas_adc(:, loc) = joint_data_n';
                thetas_volt(:, loc) = (joint_angle_conversion_factors.*(joint_data_n - joint_angle_offsets))';
                
                %Update the pressure arrays for plotting.
                ps_front_left_hip_extensor = ps_volt(1, 1:loc);
                ps_front_left_hip_flexor = ps_volt(2, 1:loc);
                ps_front_left_knee_extensor = ps_volt(3, 1:loc);
                ps_front_left_knee_flexor = ps_volt(4, 1:loc);
                ps_front_left_ankle_extensor = ps_volt(5, 1:loc);
                ps_front_left_ankle_flexor = ps_volt(6, 1:loc);
            
                %Update the joint angle arrays for plotting.
                thetas_front_left_hip = thetas_volt(1, 1:loc);
                thetas_front_left_knee1 = thetas_volt(2, 1:loc);
                thetas_front_left_knee2 = thetas_volt(3, 1:loc);
                thetas_front_left_ankle = thetas_volt(4, 1:loc);
                
                %Refresh the figure data.
                refreshdata([h_muscle_pressures, h_joint_angles], 'caller'), drawnow
                                
            end


        end

        % Value changed function: BackLeftAnkleExtensorSwitch
        function BackLeftAnkleExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackLeftAnkleExtensorSwitch, app.BackLeftAnkleExtensorGreenLamp, app.BackLeftAnkleExtensorRedLamp)
          
        end

        % Value changed function: BackLeftAnkleFlexorSwitch
        function BackLeftAnkleFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackLeftAnkleFlexorSwitch, app.BackLeftAnkleFlexorGreenLamp, app.BackLeftAnkleFlexorRedLamp)
            
        end

        % Value changed function: BackLeftHipExtensorSwitch
        function BackLeftHipExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackLeftHipExtensorSwitch, app.BackLeftHipExtensorGreenLamp, app.BackLeftHipExtensorRedLamp)
            
        end

        % Value changed function: BackLeftHipFlexorSwitch
        function BackLeftHipFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackLeftHipFlexorSwitch, app.BackLeftHipFlexorGreenLamp, app.BackLeftHipFlexorRedLamp)
            
        end

        % Value changed function: BackLeftKneeExtensorSwitch
        function BackLeftKneeExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackLeftKneeExtensorSwitch, app.BackLeftKneeExtensorGreenLamp, app.BackLeftKneeExtensorRedLamp)
          
        end

        % Value changed function: BackLeftKneeFlexorSwitch
        function BackLeftKneeFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackLeftKneeFlexorSwitch, app.BackLeftKneeFlexorGreenLamp, app.BackLeftKneeFlexorRedLamp)
            
        end

        % Value changed function: BackRightAnkleExtensorSwitch
        function BackRightAnkleExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackRightAnkleExtensorSwitch, app.BackRightAnkleExtensorGreenLamp, app.BackRightAnkleExtensorRedLamp)
             
        end

        % Value changed function: BackRightAnkleFlexorSwitch
        function BackRightAnkleFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackRightAnkleFlexorSwitch, app.BackRightAnkleFlexorGreenLamp, app.BackRightAnkleFlexorRedLamp)
               
        end

        % Value changed function: BackRightHipExtensorSwitch
        function BackRightHipExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackRightHipExtensorSwitch, app.BackRightHipExtensorGreenLamp, app.BackRightHipExtensorRedLamp)
             
        end

        % Value changed function: BackRightHipFlexorSwitch
        function BackRightHipFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackRightHipFlexorSwitch, app.BackRightHipFlexorGreenLamp, app.BackRightHipFlexorRedLamp)
             
        end

        % Value changed function: BackRightKneeExtensorSwitch
        function BackRightKneeExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackRightKneeExtensorSwitch, app.BackRightKneeExtensorGreenLamp, app.BackRightKneeExtensorRedLamp)
             
        end

        % Value changed function: BackRightKneeFlexorSwitch
        function BackRightKneeFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.BackRightKneeFlexorSwitch, app.BackRightKneeFlexorGreenLamp, app.BackRightKneeFlexorRedLamp)
               
        end

        % Value changed function: FrontLeftAnkleExtensorSwitch
        function FrontLeftAnkleExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontLeftAnkleExtensorSwitch, app.FrontLeftAnkleExtensorGreenLamp, app.FrontLeftAnkleExtensorRedLamp)
          
        end

        % Value changed function: FrontLeftAnkleFlexorSwitch
        function FrontLeftAnkleFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontLeftAnkleFlexorSwitch, app.FrontLeftAnkleFlexorGreenLamp, app.FrontLeftAnkleFlexorRedLamp)
            
        end

        % Value changed function: FrontLeftHipExtensorSwitch
        function FrontLeftHipExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontLeftHipExtensorSwitch, app.FrontLeftHipExtensorGreenLamp, app.FrontLeftHipExtensorRedLamp)
            
        end

        % Value changed function: FrontLeftHipFlexorSwitch
        function FrontLeftHipFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontLeftHipFlexorSwitch, app.FrontLeftHipFlexorGreenLamp, app.FrontLeftHipFlexorRedLamp)
            
        end

        % Value changed function: FrontLeftKneeExtensorSwitch
        function FrontLeftKneeExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontLeftKneeExtensorSwitch, app.FrontLeftKneeExtensorGreenLamp, app.FrontLeftKneeExtensorRedLamp)
            
        end

        % Value changed function: FrontLeftKneeFlexorSwitch
        function FrontLeftKneeFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontLeftKneeFlexorSwitch, app.FrontLeftKneeFlexorGreenLamp, app.FrontLeftKneeFlexorRedLamp)
            
        end

        % Value changed function: FrontRightAnkleExtensorSwitch
        function FrontRightAnkleExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontRightAnkleExtensorSwitch, app.FrontRightAnkleExtensorGreenLamp, app.FrontRightAnkleExtensorRedLamp)
             
        end

        % Value changed function: FrontRightAnkleFlexorSwitch
        function FrontRightAnkleFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontRightAnkleFlexorSwitch, app.FrontRightAnkleFlexorGreenLamp, app.FrontRightAnkleFlexorRedLamp)
             
        end

        % Value changed function: FrontRightHipExtensorSwitch
        function FrontRightHipExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontRightHipExtensorSwitch, app.FrontRightHipExtensorGreenLamp, app.FrontRightHipExtensorRedLamp)
            
        end

        % Value changed function: FrontRightHipFlexorSwitch
        function FrontRightHipFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontRightHipFlexorSwitch, app.FrontRightHipFlexorGreenLamp, app.FrontRightHipFlexorRedLamp)
             
        end

        % Value changed function: FrontRightKneeExtensorSwitch
        function FrontRightKneeExtensorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontRightKneeExtensorSwitch, app.FrontRightKneeExtensorGreenLamp, app.FrontRightKneeExtensorRedLamp)
            
        end

        % Value changed function: FrontRightKneeFlexorSwitch
        function FrontRightKneeFlexorSwitchValueChanged(app, event)
            
            %Set the status of the lamp associated with this switch.
            SetLampStatus(app, app.FrontRightKneeFlexorSwitch, app.FrontRightKneeFlexorGreenLamp, app.FrontRightKneeFlexorRedLamp)
             
        end

        % Close request function: SimpleMuscleControlAppUIFigure
        function SimpleMuscleControlAppUIFigureCloseRequest(app, event)
            
            %Delete the app.
            delete(app);
            
            %Close the serial port.
            CloseSerialPort(serial_port)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleMuscleControlAppUIFigure = uifigure;
            app.SimpleMuscleControlAppUIFigure.Name = 'Simple Muscle Control App';
            app.SimpleMuscleControlAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimpleMuscleControlAppUIFigureCloseRequest, true);
            app.HIPSwitchLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitchLabel.Text = 'HIP';
            app.FrontLeftHipExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontLeftHipExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontLeftHipExtensorSwitchValueChanged, true);
            app.FrontLeftHipExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftHipExtensorGreenLamp.Enable = 'off';
            app.FrontLeftHipFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftHipFlexorGreenLamp.Enable = 'off';
            app.HIPSwitch_3Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_3Label.Text = 'HIP';
            app.FrontLeftHipFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontLeftHipFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontLeftHipFlexorSwitchValueChanged, true);
            app.FrontLeftHipFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftHipExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftKneeExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftKneeExtensorGreenLamp.Enable = 'off';
            app.FrontLeftKneeExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitchLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitchLabel.Text = 'KNEE';
            app.FrontLeftKneeExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontLeftKneeExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontLeftKneeExtensorSwitchValueChanged, true);
            app.FrontLeftKneeFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftKneeFlexorGreenLamp.Enable = 'off';
            app.FrontLeftKneeFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_3Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_3Label.Text = 'KNEE';
            app.FrontLeftKneeFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontLeftKneeFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontLeftKneeFlexorSwitchValueChanged, true);
            app.FrontLeftAnkleExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftAnkleExtensorGreenLamp.Enable = 'off';
            app.FrontLeftAnkleExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitchLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitchLabel.Text = 'ANKLE';
            app.FrontLeftAnkleExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontLeftAnkleExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontLeftAnkleExtensorSwitchValueChanged, true);
            app.FrontLeftAnkleFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontLeftAnkleFlexorGreenLamp.Enable = 'off';
            app.FrontLeftAnkleFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_3Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_3Label.Text = 'ANKLE';
            app.FrontLeftAnkleFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontLeftAnkleFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontLeftAnkleFlexorSwitchValueChanged, true);
            app.BackLeftHipExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftHipExtensorGreenLamp.Enable = 'off';
            app.BackLeftHipFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftHipFlexorGreenLamp.Enable = 'off';
            app.BackLeftHipFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftHipExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftKneeExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftKneeExtensorGreenLamp.Enable = 'off';
            app.BackLeftKneeExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftKneeFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftKneeFlexorGreenLamp.Enable = 'off';
            app.BackLeftKneeFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftAnkleExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftAnkleExtensorGreenLamp.Enable = 'off';
            app.BackLeftAnkleExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftAnkleFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackLeftAnkleFlexorGreenLamp.Enable = 'off';
            app.BackLeftAnkleFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_2Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_2Label.Text = 'HIP';
            app.BackLeftHipExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackLeftHipExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackLeftHipExtensorSwitchValueChanged, true);
            app.HIPSwitch_4Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_4Label.Text = 'HIP';
            app.BackLeftHipFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackLeftHipFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackLeftHipFlexorSwitchValueChanged, true);
            app.KNEESwitch_2Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_2Label.Text = 'KNEE';
            app.BackLeftKneeExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackLeftKneeExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackLeftKneeExtensorSwitchValueChanged, true);
            app.KNEESwitch_4Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_4Label.Text = 'KNEE';
            app.BackLeftKneeFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackLeftKneeFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackLeftKneeFlexorSwitchValueChanged, true);
            app.ANKLESwitch_2Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_2Label.Text = 'ANKLE';
            app.BackLeftAnkleExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackLeftAnkleExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackLeftAnkleExtensorSwitchValueChanged, true);
            app.ANKLESwitch_4Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_4Label.Text = 'ANKLE';
            app.BackLeftAnkleFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackLeftAnkleFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackLeftAnkleFlexorSwitchValueChanged, true);
            app.EXTENSORSLabel_Left = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.EXTENSORSLabel_Left.Text = 'EXTENSORS';
            app.FLEXORSLabel_Left = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.FLEXORSLabel_Left.Text = 'FLEXORS';
            app.FRONTLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.FRONTLabel.Text = 'FRONT';
            app.BACKLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.BACKLabel.Text = 'BACK';
            app.MusclePressureAxes = uiaxes(app.SimpleMuscleControlAppUIFigure);
            title(app.MusclePressureAxes, 'Muscle Pressure vs Time')
            xlabel(app.MusclePressureAxes, 'Time Step [#]')
            ylabel(app.MusclePressureAxes, 'Pressure [V]')
            app.MusclePressureAxes.XLim = [0 1];
            app.MusclePressureAxes.XLimMode = 'manual';
            app.MusclePressureAxes.YLim = [0 1];
            app.MusclePressureAxes.YLimMode = 'manual';
            app.MusclePressureAxes.CLim = [0 1];
            app.MusclePressureAxes.CLimMode = 'manual';
            app.MusclePressureAxes.XTick = [0 0.5 1];
            app.MusclePressureAxes.YTick = [0 0.5 1];
            app.LEFTLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.LEFTLabel.Text = 'LEFT';
            app.EXTENSORSLabel_Right = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.EXTENSORSLabel_Right.Text = 'EXTENSORS';
            app.HIPSwitch_5Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_5Label.Text = 'HIP';
            app.FrontRightHipExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontRightHipExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontRightHipExtensorSwitchValueChanged, true);
            app.FrontRightHipExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontRightHipExtensorGreenLamp.Enable = 'off';
            app.FrontRightHipExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_5Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_5Label.Text = 'KNEE';
            app.FrontRightKneeExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontRightKneeExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontRightKneeExtensorSwitchValueChanged, true);
            app.FrontRightKneeExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontRightKneeExtensorGreenLamp.Enable = 'off';
            app.FrontRightKneeExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_5Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_5Label.Text = 'ANKLE';
            app.FrontRightAnkleExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontRightAnkleExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontRightAnkleExtensorSwitchValueChanged, true);
            app.FrontRightAnkleExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontRightAnkleExtensorGreenLamp.Enable = 'off';
            app.FrontRightAnkleExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_6Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_6Label.Text = 'HIP';
            app.BackRightHipExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackRightHipExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackRightHipExtensorSwitchValueChanged, true);
            app.BackRightHipExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackRightHipExtensorGreenLamp.Enable = 'off';
            app.BackRightHipExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_6Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_6Label.Text = 'KNEE';
            app.BackRightKneeExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackRightKneeExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackRightKneeExtensorSwitchValueChanged, true);
            app.BackRightKneeExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackRightKneeExtensorGreenLamp.Enable = 'off';
            app.BackRightKneeExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_6Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_6Label.Text = 'ANKLE';
            app.BackRightAnkleExtensorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackRightAnkleExtensorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackRightAnkleExtensorSwitchValueChanged, true);
            app.BackRightAnkleExtensorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackRightAnkleExtensorGreenLamp.Enable = 'off';
            app.BackRightAnkleExtensorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FLEXORSLabel_Right = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.FLEXORSLabel_Right.Text = 'FLEXORS';
            app.HIPSwitch_7Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_7Label.Text = 'HIP';
            app.FrontRightHipFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontRightHipFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontRightHipFlexorSwitchValueChanged, true);
            app.FrontRightHipFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontRightHipFlexorGreenLamp.Enable = 'off';
            app.FrontRightHipFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_7Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_7Label.Text = 'KNEE';
            app.FrontRightKneeFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontRightKneeFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontRightKneeFlexorSwitchValueChanged, true);
            app.FrontRightKneeFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontRightKneeFlexorGreenLamp.Enable = 'off';
            app.FrontRightKneeFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_7Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_7Label.Text = 'ANKLE';
            app.FrontRightAnkleFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.FrontRightAnkleFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @FrontRightAnkleFlexorSwitchValueChanged, true);
            app.FrontRightAnkleFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.FrontRightAnkleFlexorGreenLamp.Enable = 'off';
            app.FrontRightAnkleFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_8Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.HIPSwitch_8Label.Text = 'HIP';
            app.BackRightHipFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackRightHipFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackRightHipFlexorSwitchValueChanged, true);
            app.BackRightHipFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackRightHipFlexorGreenLamp.Enable = 'off';
            app.BackRightHipFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_8Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.KNEESwitch_8Label.Text = 'KNEE';
            app.BackRightKneeFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackRightKneeFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackRightKneeFlexorSwitchValueChanged, true);
            app.BackRightKneeFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackRightKneeFlexorGreenLamp.Enable = 'off';
            app.BackRightKneeFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_8Label = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.ANKLESwitch_8Label.Text = 'ANKLE';
            app.BackRightAnkleFlexorSwitch = uiswitch(app.SimpleMuscleControlAppUIFigure, 'slider');
            app.BackRightAnkleFlexorSwitch.ValueChangedFcn = createCallbackFcn(app, @BackRightAnkleFlexorSwitchValueChanged, true);
            app.BackRightAnkleFlexorGreenLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.BackRightAnkleFlexorGreenLamp.Enable = 'off';
            app.BackRightAnkleFlexorRedLamp = uilamp(app.SimpleMuscleControlAppUIFigure);
            app.RIGHTLabel = uilabel(app.SimpleMuscleControlAppUIFigure);
            app.RIGHTLabel.Text = 'RIGHT';
            app.JointAngleAxes = uiaxes(app.SimpleMuscleControlAppUIFigure);
            title(app.JointAngleAxes, 'Joint Angle vs Time')
            xlabel(app.JointAngleAxes, 'Time Step [#]')
            ylabel(app.JointAngleAxes, 'Joint Angle [V]')
            app.JointAngleAxes.XLim = [0 1];
            app.JointAngleAxes.XLimMode = 'manual';
            app.JointAngleAxes.YLim = [0 1];
            app.JointAngleAxes.YLimMode = 'manual';
            app.JointAngleAxes.CLim = [0 1];
            app.JointAngleAxes.CLimMode = 'manual';
            app.JointAngleAxes.XTick = [0 0.5 1];
            app.JointAngleAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.JointAngleAxes.YTick = [0 0.5 1];
            app.JointAngleAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Simple_Muscle_Control_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleMuscleControlAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleMuscleControlAppUIFigure)
        end
    end
end